classdef fmon_prefs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        H2OTab                         matlab.ui.container.Tab
        mlLabel_3                      matlab.ui.control.Label
        mlLabel_2                      matlab.ui.control.Label
        mlLabel                        matlab.ui.control.Label
        sLabel_3                       matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        InitValveEditField_2           matlab.ui.control.NumericEditField
        InitValveEditFieldLabel_2      matlab.ui.control.Label
        RightValveEditField_2          matlab.ui.control.NumericEditField
        RightValveEditFieldLabel_2     matlab.ui.control.Label
        LeftValveEditField_2           matlab.ui.control.NumericEditField
        LeftValveEditFieldLabel_2      matlab.ui.control.Label
        Rewardamountfor100repsLabel    matlab.ui.control.Label
        CalibrateValveTimingButton     matlab.ui.control.StateButton
        FlushWaterTubesButton          matlab.ui.control.StateButton
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        NotesTextArea                  matlab.ui.control.TextArea
        NotesTextAreaLabel             matlab.ui.control.Label
        InitValveEditField             matlab.ui.control.NumericEditField
        InitValveEditFieldLabel        matlab.ui.control.Label
        RightValveEditField            matlab.ui.control.NumericEditField
        RightValveEditFieldLabel       matlab.ui.control.Label
        LeftValveEditField             matlab.ui.control.NumericEditField
        LeftValveEditFieldLabel        matlab.ui.control.Label
        RewardPortTimingsLabel         matlab.ui.control.Label
        FMONRigWaterSetupLabel         matlab.ui.control.Label
        SessionInfoTab                 matlab.ui.container.Tab
        OdorantDropDown                matlab.ui.control.DropDown
        OdorantDropDownLabel           matlab.ui.control.Label
        SniffingDropDown               matlab.ui.control.DropDown
        SniffingDropDownLabel          matlab.ui.control.Label
        ExperimentInformationLabel     matlab.ui.control.Label
        SessionInformationLabel        matlab.ui.control.Label
        SubmitInformationButton        matlab.ui.control.Button
        TotalInjectionEditField        matlab.ui.control.EditField
        TotalInjectionEditFieldLabel   matlab.ui.control.Label
        ProtocolDropDownLabel          matlab.ui.control.Label
        ProtocolDropDown               matlab.ui.control.DropDown
        SessionLengthEditField         matlab.ui.control.NumericEditField
        SessionLengthEditFieldLabel    matlab.ui.control.Label
        SideBiasDropDownLabel          matlab.ui.control.Label
        SideBiasDropDown               matlab.ui.control.DropDown
        DrugDoseEditField              matlab.ui.control.EditField
        DrugDoseEditFieldLabel         matlab.ui.control.Label
        PercentOmissionEditField       matlab.ui.control.NumericEditField
        PercentOmissionEditFieldLabel  matlab.ui.control.Label
        MaxITIEditField                matlab.ui.control.NumericEditField
        MaxITIEditFieldLabel           matlab.ui.control.Label
        MinITIEditField                matlab.ui.control.NumericEditField
        MinITIEditFieldLabel           matlab.ui.control.Label
        InitialWeightEditField         matlab.ui.control.EditField
        InitialWeightEditFieldLabel    matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.EditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        ThermistorTypeDropDownLabel    matlab.ui.control.Label
        ThermistorTypeDropDown         matlab.ui.control.DropDown
        MouseSexDropDownLabel          matlab.ui.control.Label
        MouseSexDropDown               matlab.ui.control.DropDown
        TrainerEditField               matlab.ui.control.EditField
        TrainerEditFieldLabel          matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        RunProtocolTab                 matlab.ui.container.Tab
        ProtocolNotSpecifiedButton     matlab.ui.control.Button
        FMONPreferencesLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        protocol = '';
        info_submitted = false;
        fmon_task_toggle = false;
    end
    
    methods (Access = private)
        
        function ConnectToBonsai(junk, otherjunk)
            global BpodSystem
            if isempty(BpodSystem.BonsaiSocket)
                %set(BpodSystem.GUIHandles.BonsaiConnectStatus, 'String', 'Connecting', 'ForegroundColor', 'y'); drawnow;
                try
                    BpodSystem.BonsaiSocket = TCPCom(11235);
                catch
                %    set(BpodSystem.GUIHandles.BonsaiConnectStatus, 'String', 'Disconnected', 'ForegroundColor', 'r');
                    rethrow(lasterror);
                end
                %set(BpodSystem.GUIHandles.BonsaiConnectStatus, 'String', 'Connected', 'ForegroundColor', 'g');
                %set(BpodSystem.GUIHandles.BonsaiConnectButton, 'CData', BpodSystem.GUIHandles.BonsaiDisconnectButtonGFX);
            else
                 BpodSystem.BonsaiSocket = [];
                 %set(BpodSystem.GUIHandles.BonsaiConnectStatus, 'String', 'Disconnected', 'ForegroundColor', 'r');
                 %set(BpodSystem.GUIHandles.BonsaiConnectButton, 'CData', BpodSystem.GUIHandles.BonsaiConnectButtonGFX);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SubmitInformationButton
        function SubmitInformationButtonPushed(app, event)
            % Researcher Info
            experimenter = app.ExperimenterEditField.Value;
            trainer = app.TrainerEditField.Value;
            
            % Mouse Info
            protocol_name = app.ProtocolDropDown.Value;
            mouse_id = app.MouseIDEditField.Value;
            mouse_sex = app.MouseSexDropDown.Value;
            initial_weight = app.InitialWeightEditField.Value;        
            thermistor_type = app.ThermistorTypeDropDown.Value;
            odorant = app.OdorantDropDown.Value;
            sniffing = app.SniffingDropDown.Value;

            % Protocol Variables
            session_duration = app.SessionLengthEditField.Value;
            min_iti = app.MinITIEditField.Value;
            max_iti = app.MaxITIEditField.Value;
            side_bias = app.SideBiasDropDown.Value;
            drug_dose = app.DrugDoseEditField.Value;
            drug_dose_total = app.TotalInjectionEditField.Value;
            pct_omission = app.PercentOmissionEditField.Value;
                        
            % Error checks to ensure all fields are complete
            % Experimenter Info
            if isempty(app.ExperimenterEditField.Value)
                uialert(app.UIFigure, 'Please input Researcher name.', 'Error');
            elseif isempty(app.TrainerEditField.Value)
                uialert(app.UIFigure, 'Please input Trainer name.', 'Error');
                
            % Mouse Info    
            elseif isempty(app.ProtocolDropDown.Value)
                uialert(app.UIFigure, 'Please specify Protocol.', 'Error');
            elseif isempty(app.MouseIDEditField.Value)
                uialert(app.UIFigure, 'Please input Mouse ID.', 'Error');
            elseif isempty(app.MouseSexDropDown.Value)
                uialert(app.UIFigure, 'Please specify Mouse Sex.', 'Error');
            elseif isempty(app.InitialWeightEditField.Value)
                uialert(app.UIFigure, 'Please input Initial Weight', 'Error');
            elseif isempty(app.ThermistorTypeDropDown.Value)
                uialert(app.UIFigure, 'Please input Thermistor Type.', 'Error');
            elseif isempty(app.OdorantDropDown.Value)
                uialert(app.UIFigure, 'Please input Odorant Type.', 'Error');  
            elseif isempty(app.SniffingDropDown.Value)
                uialert(app.UIFigure, 'Please indicate Sniffing Yes/No.', 'Error');  
                
            % Protocol Info    
            elseif isempty(app.SessionLengthEditField.Value)
                uialert(app.UIFigure, 'Please input Session Duration.', 'Error');             
            elseif isempty(app.MinITIEditField.Value)
                uialert(app.UIFigure, 'Please input Min ITI Duration.', 'Error');
            elseif isempty(app.MaxITIEditField.Value)
                uialert(app.UIFigure, 'Please input Max ITI Duration.', 'Error');
            elseif isempty(app.DrugDoseEditField.Value)
                uialert(app.UIFigure, 'Please input Drug Dose mg/kg.', 'Error');
            elseif isempty(app.TotalInjectionEditField.Value)
                uialert(app.UIFigure, 'Please input Total Drug Dose.', 'Error');    
            elseif isempty(app.PercentOmissionEditField.Value)
                uialert(app.UIFigure, 'Please input Percentage of Omission Trials.', 'Error');    
            else
                
            % If all fields are complete, save variables to base workspace
            assignin('base', 'protocol', protocol_name);
            assignin('base', 'mouse_id', mouse_id);
            assignin('base', 'mouse_sex', mouse_sex);
            assignin('base', 'initial_weight', initial_weight);
            assignin('base', 'thermistor_type', thermistor_type);
            assignin('base', 'odorant', odorant);
            assignin('base', 'sniffing', sniffing);
            assignin('base', 'experimenter', experimenter);
            assignin('base', 'trainer', trainer);
            assignin('base', 'session_duration', session_duration);
            assignin('base', 'min_iti', min_iti);
            assignin('base', 'max_iti', max_iti);
            assignin('base', 'side_bias', side_bias);
            assignin('base', 'drug_dose', drug_dose);
            assignin('base', 'drug_dose_total', drug_dose_total);
            assignin('base', 'pct_omission', pct_omission);
            assignin('base', 'fmon_task_toggle',  app.fmon_task_toggle);
            
            % Confirmation Alert
            uialert(app.UIFigure, 'Session Info Submitted!', 'Success', 'Icon', 'success'); 
            
            % Allow user to click run protocol tab
            app.info_submitted = true;
            
            % Create subject (mouse id) folder for Bpod to store data
            folderName = 'D:\Bpod Local\Data\' + string(mouse_id);
                if ~exist(folderName, 'dir')
                    mkdir(folderName);
                end      
            end      
        end

        % Callback function
        function FlushTubesButtonPushed(app, event)
                        
            % Set all valve times to 1 sec
            assignin('base', 'LeftValveTime', 1);  
            assignin('base', 'RightValveTime', 1);
            assignin('base', 'InitValveTime', 1);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')
            
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
                       
            % Set valve time based on text field
            assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);  
            assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')
            
        end

        % Callback function
        function StopButtonPushed(app, event)
               RunProtocol('Stop')
        end

        % Value changed function: ProtocolDropDown
        function ProtocolDropDownValueChanged(app, event)
            value = app.ProtocolDropDown.Value;            
            % Set Variables based on protocol defaults
                switch value
                    case 'Trainer 1'
                        app.SessionLengthEditField.Value = 30;
                        app.MinITIEditField.Value = 0;
                        app.MaxITIEditField.Value = 0;
                        app.PercentOmissionEditField.Value = 0;
                        app.protocol = 'fmon_train';
                        app.ProtocolNotSpecifiedButton.Text = 'Run Trainer 1';
                        app.fmon_task_toggle = false;
                        assignin('base', 'fmon_task_toggle', app.fmon_task_toggle);
                    case '100-0'
                        app.SessionLengthEditField.Value = 40;
                        app.MinITIEditField.Value = 1;
                        app.MaxITIEditField.Value = 15;
                        app.PercentOmissionEditField.Value = 10;
                        app.protocol = 'fmon_task';
                        app.ProtocolNotSpecifiedButton.Text = 'Run 100-0';
                        app.fmon_task_toggle = true;
                        assignin('base', 'fmon_task_toggle', app.fmon_task_toggle);
                    case 'Spontaneous'
                        app.SessionLengthEditField.Value = 60;
                        app.MinITIEditField.Value = 0;
                        app.MaxITIEditField.Value = 0;
                        app.PercentOmissionEditField.Value = 0;
                        app.protocol = 'fmon_spontaneous';
                        app.ProtocolNotSpecifiedButton.Text = 'Run Spontaneous';
                        app.fmon_task_toggle = false;
                        assignin('base', 'fmon_task_toggle', app.fmon_task_toggle);
                end
            
        end

        % Button pushed function: ProtocolNotSpecifiedButton
        function ProtocolNotSpecifiedButtonPushed(app, event)
            if isempty(app.ProtocolDropDown.Value)
                uialert(app.UIFigure, 'You must specify a protocol in the Session Info tab.', 'Error');
            else
                RunProtocol('Start', app.protocol, app.MouseIDEditField.Value);                
            end
        end

        % Value changed function: FlushWaterTubesButton
        function FlushWaterTubesButtonValueChanged(app, event)
            % Update Button State
            value = app.FlushWaterTubesButton.Value;
            app.FlushWaterTubesButton.Text = 'Stop Flushing Tubes';
            
            % Set all water valve times to 1 sec (flush)
            assignin('base', 'LeftValveTime', 1);  
            assignin('base', 'RightValveTime', 1);
            assignin('base', 'InitValveTime', 1);
            
            % Run water calibration protocol
            if value == true
                RunProtocol('Start', 'calibrate_h2o', 'FakeSubject');  
            else
                RunProtocol('Stop');
                app.FlushWaterTubesButton.Text = 'Flush Water Tubes';                
            end
       
        end

        % Value changed function: CalibrateValveTimingButton
        function CalibrateValveTimingButtonValueChanged(app, event)
            % Update Button State
            value = app.CalibrateValveTimingButton.Value;
            app.CalibrateValveTimingButton.Text = 'Stop Calibration';
            
            % Set valve time based on text field
            if app.CheckBox.Value == 1
                assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);
            else
                assignin('base', 'LeftValveTime', 0);
            end
            
            if app.CheckBox_2.Value == 1
                assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            else
                assignin('base', 'RightValveTime', 0);
            end
            
            if app.CheckBox_3.Value == 1
                assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            else
                assignin('base', 'InitValveTime', 0);
            end
                   
            % Run water calibration protocol
            if value == true
                RunProtocol('Start', 'calibrate_h2o', 'FakeSubject');  
            else
                RunProtocol('Stop');
                app.CalibrateValveTimingButton.Text = 'Calibrate Water Valves';                
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, 'Run Protocol') 
                if app.info_submitted == false
                    app.TabGroup.SelectedTab = app.SessionInfoTab;
                    uialert(app.UIFigure, 'Please submit session info.', 'Error'); 
                end
            end
      
            % Set Valve Timings based on text fields. They May have been
            % set to zero if corresponding box was unchecked.
            assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);
            assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            assignin('base', 'LeftValveVolume', app.LeftValveEditField_2.Value);
            assignin('base', 'RightValveVolume', app.RightValveEditField_2.Value);
            assignin('base', 'InitValveVolume', app.InitValveEditField_2.Value);
        end

        % Value changed function: LeftValveEditField
        function LeftValveEditFieldValueChanged(app, event)
            value = app.LeftValveEditField.Value;
            assignin('base', 'LeftValveTime', value);
        end

        % Value changed function: RightValveEditField
        function RightValveEditFieldValueChanged(app, event)
            value = app.RightValveEditField.Value;
            assignin('base', 'RightValveTime', value);
        end

        % Value changed function: InitValveEditField
        function InitValveEditFieldValueChanged(app, event)
            value = app.InitValveEditField.Value;
            assignin('base', 'InitValveTime', value);
        end

        % Value changed function: LeftValveEditField_2
        function LeftValveEditField_2ValueChanged(app, event)
            value = app.LeftValveEditField_2.Value;
            assignin('base', 'LeftValveVolume', value);
        end

        % Value changed function: RightValveEditField_2
        function RightValveEditField_2ValueChanged(app, event)
            value = app.RightValveEditField_2.Value;
            assignin('base', 'RightValveVolume', value);
        end

        % Value changed function: InitValveEditField_2
        function InitValveEditField_2ValueChanged(app, event)
            value = app.InitValveEditField_2.Value;
            assignin('base', 'InitValveVolume', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMONPreferencesLabel = uilabel(app.UIFigure);
            app.FMONPreferencesLabel.Tooltip = {'Your name'};
            app.FMONPreferencesLabel.Text = 'FMON Preferences';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.H2OTab = uitab(app.TabGroup);
            app.H2OTab.Title = 'H2O';
            app.FMONRigWaterSetupLabel = uilabel(app.H2OTab);
            app.FMONRigWaterSetupLabel.Text = 'FMON Rig - Water Setup';
            app.RewardPortTimingsLabel = uilabel(app.H2OTab);
            app.RewardPortTimingsLabel.Text = 'Reward Port Timings';
            app.LeftValveEditFieldLabel = uilabel(app.H2OTab);
            app.LeftValveEditFieldLabel.Text = 'Left Valve';
            app.LeftValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.LeftValveEditField.Limits = [0.1 1];
            app.LeftValveEditField.ValueChangedFcn = createCallbackFcn(app, @LeftValveEditFieldValueChanged, true);
            app.LeftValveEditField.Value = 0.1;
            app.RightValveEditFieldLabel = uilabel(app.H2OTab);
            app.RightValveEditFieldLabel.Text = 'Right Valve';
            app.RightValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.RightValveEditField.Limits = [0.1 1];
            app.RightValveEditField.ValueChangedFcn = createCallbackFcn(app, @RightValveEditFieldValueChanged, true);
            app.RightValveEditField.Value = 0.1;
            app.InitValveEditFieldLabel = uilabel(app.H2OTab);
            app.InitValveEditFieldLabel.Text = 'Init. Valve';
            app.InitValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.InitValveEditField.Limits = [0.1 1];
            app.InitValveEditField.ValueChangedFcn = createCallbackFcn(app, @InitValveEditFieldValueChanged, true);
            app.InitValveEditField.Value = 0.1;
            app.NotesTextAreaLabel = uilabel(app.H2OTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.NotesTextArea = uitextarea(app.H2OTab);
            app.CheckBox = uicheckbox(app.H2OTab);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.H2OTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.H2OTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.FlushWaterTubesButton = uibutton(app.H2OTab, 'state');
            app.FlushWaterTubesButton.ValueChangedFcn = createCallbackFcn(app, @FlushWaterTubesButtonValueChanged, true);
            app.FlushWaterTubesButton.Text = 'Flush Water Tubes';
            app.CalibrateValveTimingButton = uibutton(app.H2OTab, 'state');
            app.CalibrateValveTimingButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateValveTimingButtonValueChanged, true);
            app.CalibrateValveTimingButton.Text = 'Calibrate Valve Timing';
            app.Rewardamountfor100repsLabel = uilabel(app.H2OTab);
            app.Rewardamountfor100repsLabel.Text = 'Reward amount for 100 reps';
            app.LeftValveEditFieldLabel_2 = uilabel(app.H2OTab);
            app.LeftValveEditFieldLabel_2.Text = 'Left Valve';
            app.LeftValveEditField_2 = uieditfield(app.H2OTab, 'numeric');
            app.LeftValveEditField_2.Limits = [0 10];
            app.LeftValveEditField_2.ValueChangedFcn = createCallbackFcn(app, @LeftValveEditField_2ValueChanged, true);
            app.RightValveEditFieldLabel_2 = uilabel(app.H2OTab);
            app.RightValveEditFieldLabel_2.Text = 'Right Valve';
            app.RightValveEditField_2 = uieditfield(app.H2OTab, 'numeric');
            app.RightValveEditField_2.Limits = [0 10];
            app.RightValveEditField_2.ValueChangedFcn = createCallbackFcn(app, @RightValveEditField_2ValueChanged, true);
            app.InitValveEditFieldLabel_2 = uilabel(app.H2OTab);
            app.InitValveEditFieldLabel_2.Text = 'Init. Valve';
            app.InitValveEditField_2 = uieditfield(app.H2OTab, 'numeric');
            app.InitValveEditField_2.Limits = [0 10];
            app.InitValveEditField_2.ValueChangedFcn = createCallbackFcn(app, @InitValveEditField_2ValueChanged, true);
            app.sLabel = uilabel(app.H2OTab);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.H2OTab);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.H2OTab);
            app.sLabel_3.Text = 's';
            app.mlLabel = uilabel(app.H2OTab);
            app.mlLabel.Text = 'ml';
            app.mlLabel_2 = uilabel(app.H2OTab);
            app.mlLabel_2.Text = 'ml';
            app.mlLabel_3 = uilabel(app.H2OTab);
            app.mlLabel_3.Text = 'ml';
            app.SessionInfoTab = uitab(app.TabGroup);
            app.SessionInfoTab.Title = 'Session Info';
            app.ExperimenterEditFieldLabel = uilabel(app.SessionInfoTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.SessionInfoTab, 'text');
            app.TrainerEditFieldLabel = uilabel(app.SessionInfoTab);
            app.TrainerEditFieldLabel.Text = 'Trainer';
            app.TrainerEditField = uieditfield(app.SessionInfoTab, 'text');
            app.MouseSexDropDown = uidropdown(app.SessionInfoTab);
            app.MouseSexDropDown.Items = {'Male', 'Female', ''};
            app.MouseSexDropDown.Value = '';
            app.MouseSexDropDownLabel = uilabel(app.SessionInfoTab);
            app.MouseSexDropDownLabel.Text = 'Mouse Sex';
            app.ThermistorTypeDropDown = uidropdown(app.SessionInfoTab);
            app.ThermistorTypeDropDown.Items = {'Glass', 'Polymer', 'None', ''};
            app.ThermistorTypeDropDown.Value = '';
            app.ThermistorTypeDropDownLabel = uilabel(app.SessionInfoTab);
            app.ThermistorTypeDropDownLabel.Text = 'Thermistor Type';
            app.MouseIDEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.SessionInfoTab, 'text');
            app.InitialWeightEditFieldLabel = uilabel(app.SessionInfoTab);
            app.InitialWeightEditFieldLabel.Text = 'Initial Weight';
            app.InitialWeightEditField = uieditfield(app.SessionInfoTab, 'text');
            app.InitialWeightEditField.Value = ' ';
            app.MinITIEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MinITIEditFieldLabel.Text = 'Min ITI';
            app.MinITIEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.MinITIEditField.Value = 1;
            app.MaxITIEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MaxITIEditFieldLabel.Text = 'Max ITI';
            app.MaxITIEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.MaxITIEditField.Value = 5;
            app.PercentOmissionEditFieldLabel = uilabel(app.SessionInfoTab);
            app.PercentOmissionEditFieldLabel.Text = 'Percent Omission';
            app.PercentOmissionEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.PercentOmissionEditField.Value = 10;
            app.DrugDoseEditFieldLabel = uilabel(app.SessionInfoTab);
            app.DrugDoseEditFieldLabel.Text = 'Drug Dose';
            app.DrugDoseEditField = uieditfield(app.SessionInfoTab, 'text');
            app.DrugDoseEditField.Value = ' mg/kg';
            app.SideBiasDropDown = uidropdown(app.SessionInfoTab);
            app.SideBiasDropDown.Items = {'None', 'Left', 'Right', '', ''};
            app.SideBiasDropDown.Value = 'None';
            app.SideBiasDropDownLabel = uilabel(app.SessionInfoTab);
            app.SideBiasDropDownLabel.Text = 'Side Bias';
            app.SessionLengthEditFieldLabel = uilabel(app.SessionInfoTab);
            app.SessionLengthEditFieldLabel.Text = 'Session Length';
            app.SessionLengthEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.SessionLengthEditField.Value = 30;
            app.ProtocolDropDown = uidropdown(app.SessionInfoTab);
            app.ProtocolDropDown.Items = {'Trainer 1', '100-0', 'Spontaneous', ''};
            app.ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolDropDownValueChanged, true);
            app.ProtocolDropDown.Value = '';
            app.ProtocolDropDownLabel = uilabel(app.SessionInfoTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.TotalInjectionEditFieldLabel = uilabel(app.SessionInfoTab);
            app.TotalInjectionEditFieldLabel.Text = 'Total Injection';
            app.TotalInjectionEditField = uieditfield(app.SessionInfoTab, 'text');
            app.TotalInjectionEditField.Value = ' ml';
            app.SubmitInformationButton = uibutton(app.SessionInfoTab, 'push');
            app.SubmitInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitInformationButtonPushed, true);
            app.SubmitInformationButton.Text = 'Submit Information';
            app.SessionInformationLabel = uilabel(app.SessionInfoTab);
            app.SessionInformationLabel.Text = 'Session Information';
            app.ExperimentInformationLabel = uilabel(app.SessionInfoTab);
            app.ExperimentInformationLabel.Text = 'Experiment Information';
            app.SniffingDropDownLabel = uilabel(app.SessionInfoTab);
            app.SniffingDropDownLabel.Text = 'Sniffing';
            app.SniffingDropDown = uidropdown(app.SessionInfoTab);
            app.SniffingDropDown.Items = {'Yes', 'No', ''};
            app.SniffingDropDown.Value = '';
            app.OdorantDropDownLabel = uilabel(app.SessionInfoTab);
            app.OdorantDropDownLabel.Text = 'Odorant';
            app.OdorantDropDown = uidropdown(app.SessionInfoTab);
            app.OdorantDropDown.Items = {'None', 'Pinene 50%', '2-PE'};
            app.OdorantDropDown.Value = 'None';
            app.RunProtocolTab = uitab(app.TabGroup);
            app.RunProtocolTab.Title = 'Run Protocol';
            app.ProtocolNotSpecifiedButton = uibutton(app.RunProtocolTab, 'push');
            app.ProtocolNotSpecifiedButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolNotSpecifiedButtonPushed, true);
            app.ProtocolNotSpecifiedButton.Text = 'Protocol Not Specified';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmon_prefs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end